classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        Upload_Button_3  matlab.ui.control.Button
        Res              matlab.ui.control.Button
        Upload_Button_2  matlab.ui.control.Button
        UIAxes_target    matlab.ui.control.UIAxes
        UIAxes_Region    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Upload_Button_3
        function Upload_ButtonPushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'; ...
                              '*.*', 'All Files (*.*)'}, '载入图像');
            if isequal(file, 0)
                disp('用户取消了文件选择');
                return;
            end
            
            fullPath = fullfile(path, file);
            img = imread(fullPath);
            cla(app.UIAxes_Region); % 清空 UIAxes
            imshow(img, 'Parent', app.UIAxes_Region);
            axis(app.UIAxes_Region, 'tight'); % 调整显示范围
            disp('图片显示成功');

        end

        % Button pushed function: Upload_Button_2
        function Upload_Button_2Pushed(app, event)
            [file, path] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files (*.jpg, *.png, *.bmp)'; ...
                          '*.*', 'All Files (*.*)'}, '载入直方图模板图像');
            if isequal(file, 0)
                disp('用户取消了文件选择');
                return;
            end
            
            fullPath = fullfile(path, file);
            img = imread(fullPath);
            cla(app.UIAxes_target); % 清空 UIAxes
            imshow(img, 'Parent', app.UIAxes_target);
            axis(app.UIAxes_target, 'tight'); % 调整显示范围
            disp('模板图片显示成功');
        end

        % Callback function
        function Res_ButtonPushed(app, event)
            

            %显示原图像的灰度直方图
            if size(source_img, 3) == 3
                source_img = rgb2gray(source_img);
            end
        
            % 计算灰度直方图
            [counts, grayLevels] = imhist(source_img);
        
            % 清空目标 UIAxes 的内容
            cla(app.UIAxes_Histogram);
        
            % 在指定的 UIAxes 上绘制直方图
            bar(app.UIAxes_Histogram, grayLevels, counts, 'BarWidth', 1, 'FaceColor', 'k');
        
            % 设置标题和坐标轴标签
            title(app.UIAxes_Histogram, 'Gray Histogram');
            xlabel(app.UIAxes_Histogram, 'Gray Level');
            ylabel(app.UIAxes_Histogram, 'Pixel Count');
        end

        % Button pushed function: Res
        function ResPushed2(app, event)
            %获取两张图像
             imageHandle1 = app.UIAxes_Region.Children;
             imageHandle2 = app.UIAxes_target.Children;
           
            if ~isempty(imageHandle1) && isa(imageHandle1, 'matlab.graphics.primitive.Image')
                original_img = imageHandle1.CData;  % 获取图像数据
            else
                disp('原图像未上传！');
            end

            if ~isempty(imageHandle2) && isa(imageHandle2, 'matlab.graphics.primitive.Image')
                template_img = imageHandle2.CData;  % 获取图像数据
            else
                disp('模板图像未上传！');
            end

            % 绘制原图的灰度直方图
            [original_hist, original_gray_levels] = computeHistogram(original_img);
        
            % 计算直方图均衡化
            equalized_img = HistogramEqualization(original_img);
            [equalized_hist, ~] = computeHistogram(equalized_img);
        
            % 计算直方图匹配
            matched_img = HistogramMatching(original_img, template_img);
            [matched_hist, ~] = computeHistogram(matched_img);
        
            % 将结果显示在结果框内
            figure('Name', '处理结果', 'NumberTitle', 'off', 'Position', [100, 100, 1000, 600]);
        
            % 显示原图及其直方图
            subplot(3, 3, 1), imshow(original_img), title('原图像');
            subplot(3, 3, 2), bar(original_gray_levels, original_hist), title('原图像直方图'), xlabel('灰度值'), ylabel('像素数');
            
            % 显示直方图均衡化结果
            subplot(3, 3, 4), imshow(equalized_img), title('均衡化后图像');
            subplot(3, 3, 5), bar(original_gray_levels, equalized_hist), title('均衡化直方图'), xlabel('灰度值'), ylabel('像素数');
            
            % 显示直方图匹配结果
            subplot(3, 3, 7), imshow(matched_img), title('直方图匹配图像');
            subplot(3, 3, 8), bar(original_gray_levels, matched_hist), title('匹配后直方图'), xlabel('灰度值'), ylabel('像素数');
        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_Region = uiaxes(app.UIFigure);
            title(app.UIAxes_Region, '原图像')
            zlabel(app.UIAxes_Region, 'Z')
            app.UIAxes_Region.XLim = [0 1];
            app.UIAxes_Region.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes_target = uiaxes(app.UIFigure);
            title(app.UIAxes_target, '直方图匹配图像')
            zlabel(app.UIAxes_target, 'Z')
            app.UIAxes_target.XLim = [0 1];
            app.UIAxes_target.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.Upload_Button_2 = uibutton(app.UIFigure, 'push');
            app.Upload_Button_2.ButtonPushedFcn = createCallbackFcn(app, @Upload_Button_2Pushed, true);
            app.Upload_Button_2.Text = '上传';
            app.Res = uibutton(app.UIFigure, 'push');
            app.Res.ButtonPushedFcn = createCallbackFcn(app, @ResPushed2, true);
            app.Res.Text = '确认';
            app.Upload_Button_3 = uibutton(app.UIFigure, 'push');
            app.Upload_Button_3.ButtonPushedFcn = createCallbackFcn(app, @Upload_ButtonPushed, true);
            app.Upload_Button_3.Text = '上传';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end